classdef expert_naive_compare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        naiveEditFieldLabel   matlab.ui.control.Label
        naiveEditField        matlab.ui.control.EditField
        expertEditFieldLabel  matlab.ui.control.Label
        expertEditField       matlab.ui.control.EditField
        loadnaiveButton       matlab.ui.control.Button
        loadexpertButton      matlab.ui.control.Button
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        loadbothButton        matlab.ui.control.Button
        xKnobLabel            matlab.ui.control.Label
        xKnob                 matlab.ui.control.Knob
        yKnobLabel            matlab.ui.control.Label
        yKnob                 matlab.ui.control.Knob
        saveButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadnaiveButton
        function loadnaiveButtonPushed(app, event)
            naiveSession = string(app.naiveEditField.Value);
            currentMouse = string(app.DropDown.Value);
            fileNaive = strcat('D:\Lukas\data\animals_raw\',currentMouse,'\2019',naiveSession,'\prunned_cell_bodies.mat');
            cellsNaive = load(fileNaive);
            naiveCells = (sum(cellsNaive.a_rev_a,3))*200;
            image(app.UIAxes,naiveCells);
        end

        % Button pushed function: loadexpertButton
        function loadexpertButtonPushed(app, event)
            expertSession = string(app.expertEditField.Value);
            currentMouse = string(app.DropDown.Value);
            fileExpert = strcat('D:\Lukas\data\animals_raw\',currentMouse,'\2019',expertSession,'\prunned_cell_bodies.mat');
            cellsExpert = load(fileExpert);
            expertCells = (sum(cellsExpert.a_rev_a,3))*200;
            image(app.UIAxes2,expertCells);
        end

        % Button pushed function: loadbothButton
        function loadbothButtonPushed(app, event)
            naiveSession = string(app.naiveEditField.Value);
            currentMouse = string(app.DropDown.Value);
            fileNaive = strcat('D:\Lukas\data\animals_raw\',currentMouse,'\2019',naiveSession,'\prunned_cell_bodies.mat');
            cellsNaive = load(fileNaive);
            
            expertSession = string(app.expertEditField.Value);
            currentMouse = string(app.DropDown.Value);
            fileExpert = strcat('D:\Lukas\data\animals_raw\',currentMouse,'\2019',expertSession,'\prunned_cell_bodies.mat');
            cellsExpert = load(fileExpert);
            
            new_bodies = circshift(circshift(cellsNaive.a_rev_a,round(app.xKnob.Value),1),round(app.yKnob.Value),2);
            totalCells = (sum(cellsExpert.a_rev_a,3)+sum(new_bodies,3))*175;
            image(app.UIAxes3,totalCells);
            app.saveButton.Text = 'save?';
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            naiveSession = string(app.naiveEditField.Value);
            currentMouse = string(app.DropDown.Value);
            fileNaive = strcat('D:\Lukas\data\animals_raw\',currentMouse,'\2019',naiveSession,'\prunned_cell_bodies.mat');
            cellsNaive = load(fileNaive);
            
            res_log = cellsNaive.res_log;
            new_bodies = circshift(circshift(cellsNaive.a_rev_a,round(app.xKnob.Value),1),round(app.yKnob.Value),2);
            save(strcat('D:\Lukas\data\animals_raw\',currentMouse,'\2019',naiveSession,'\adjusted_cell_bodies.mat'),'new_bodies','res_log')
            app.saveButton.Text = 'SAVED!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.naiveEditFieldLabel = uilabel(app.UIFigure);
            app.naiveEditFieldLabel.Text = 'naive';
            app.naiveEditField = uieditfield(app.UIFigure, 'text');
            app.expertEditFieldLabel = uilabel(app.UIFigure);
            app.expertEditFieldLabel.Text = 'expert';
            app.expertEditField = uieditfield(app.UIFigure, 'text');
            app.loadnaiveButton = uibutton(app.UIFigure, 'push');
            app.loadnaiveButton.ButtonPushedFcn = createCallbackFcn(app, @loadnaiveButtonPushed, true);
            app.loadnaiveButton.Text = 'load naive';
            app.loadexpertButton = uibutton(app.UIFigure, 'push');
            app.loadexpertButton.ButtonPushedFcn = createCallbackFcn(app, @loadexpertButtonPushed, true);
            app.loadexpertButton.Text = 'load expert';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'LF191022_1', 'LF191022_2', 'LF191022_3', 'LF191023_blank', 'LF191023_blue', 'LF191024_1'};
            app.DropDown.Value = 'LF191022_1';
            app.loadbothButton = uibutton(app.UIFigure, 'push');
            app.loadbothButton.ButtonPushedFcn = createCallbackFcn(app, @loadbothButtonPushed, true);
            app.loadbothButton.Text = 'load both';
            app.xKnobLabel = uilabel(app.UIFigure);
            app.xKnobLabel.Text = 'x';
            app.xKnob = uiknob(app.UIFigure, 'continuous');
            app.xKnob.Limits = [-80 80];
            app.yKnobLabel = uilabel(app.UIFigure);
            app.yKnobLabel.Text = 'y';
            app.yKnob = uiknob(app.UIFigure, 'continuous');
            app.yKnob.Limits = [-80 80];
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save?';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Naive')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Expert')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'comapre')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expert_naive_compare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end